classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCXTCPUIFigure  matlab.ui.Figure
        Switch          matlab.ui.control.ToggleSwitch
        Label_4         matlab.ui.control.Label
        TouchLED        matlab.ui.control.Lamp
        TouchLampLabel  matlab.ui.control.Label
        Temp            matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        TempSwitch      matlab.ui.control.Switch
        BlueSwitch      matlab.ui.control.Switch
        BlueLED         matlab.ui.control.Lamp
        LEDLamp_3Label  matlab.ui.control.Label
        GreenSwitch     matlab.ui.control.Switch
        GreenLED        matlab.ui.control.Lamp
        LEDLamp_2Label  matlab.ui.control.Label
        RedSwitch       matlab.ui.control.Switch
        RedLED          matlab.ui.control.Lamp
        LEDLampLabel    matlab.ui.control.Label
        ConnectLED      matlab.ui.control.Lamp
        ConnectButton   matlab.ui.control.Button
        PortEdit        matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        IPEdit          matlab.ui.control.EditField
        IPLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        tcpClient % TCP客户端对象
        timerObj  % 定时器对象
    end

    methods (Access = private)

        function startReadingData(app)
            app.timerObj = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~) app.readData);
            start(app.timerObj);
        end

        function readData(app)
            try
                if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                    if app.tcpClient.NumBytesAvailable > 0
                        receivedData = readline(app.tcpClient);
                        fprintf('收到数据: %s\n', receivedData);

                        % 解析数据
                        dataPattern = 'Info:([\d\.]+),(\d+),(\d+)';
                        dataMatch = regexp(receivedData, dataPattern, 'tokens');
                        if ~isempty(dataMatch)
                            tempValue = str2double(dataMatch{1}{1});
                            touchValue = str2double(dataMatch{1}{2});
                            keyValue = str2double(dataMatch{1}{3});

                            % 更新温度数据
                            if app.TempSwitch.Value == "On"
                                app.Temp.Text = sprintf('%.2f℃', tempValue);
                            end

                            % 更新触摸数据
                            if touchValue == 1
                                app.TouchLED.Color = [1 0 0]; % 触摸状态，LED变红
                            else
                                app.TouchLED.Color = [0.15 0.15 0.15]; % 非触摸状态，LED变灰
                            end

                            if keyValue == 1
                                app.Switch.Value = 'On';
                            else
                                app.Switch.Value = 'Off';
                            end
                        end
                    end
                else
                    % 连接断开，执行类似点击断开按钮的效果
                    app.ConnectButtonPushed();
                end
            catch ME
                % 捕获异常并处理连接断开的情况
                app.ConnectButtonPushed();
                stop(app.timerObj);
                delete(app.timerObj);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if nargin < 2
                event = [];
            end

            if isempty(app.tcpClient) || ~isvalid(app.tcpClient)
                % 当前没有连接，尝试连接
                try
                    app.tcpClient = tcpclient(app.IPEdit.Value, app.PortEdit.Value, 'ConnectTimeout', 3, 'Timeout', 3);
                    app.ConnectLED.Color = [0 1 0]; % 连接成功，LED变绿
                    app.ConnectButton.Text = "断开";
                    app.IPEdit.Enable = 'off'; % 禁用IP编辑框
                    app.PortEdit.Enable = 'off'; % 禁用端口编辑框
                    app.RedSwitch.Enable = "on";
                    app.BlueSwitch.Enable = "on";
                    app.GreenSwitch.Enable = "on";
                    app.TempSwitch.Enable = "on";

                    app.startReadingData();
                catch ME
                    app.ConnectLED.Color = [1 0 0]; % 连接失败，LED变红
                    uialert(app.MCXTCPUIFigure, ME.message, '连接失败');
                end
            else
                % 当前已连接，断开连接
                clear app.tcpClient;
                app.tcpClient = [];
                app.ConnectLED.Color = [0.5 0.5 0.5]; % 断开连接，LED变灰
                app.ConnectButton.Text = "连接";
                app.IPEdit.Enable = 'on'; % 启用IP编辑框
                app.PortEdit.Enable = 'on'; % 启用端口编辑框
                app.RedSwitch.Enable = "off";
                app.BlueSwitch.Enable = "off";
                app.GreenSwitch.Enable = "off";
                app.TempSwitch.Enable = "off";

                % 停止并删除定时器
                if ~isempty(app.timerObj) && isvalid(app.timerObj)
                    stop(app.timerObj);
                    delete(app.timerObj);
                end
            end
        end

        % Value changed function: RedSwitch
        function RedSwitchValueChanged(app, event)
            if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                if app.RedSwitch.Value == "On"
                    writeline(app.tcpClient, "red:1");
                    app.RedLED.Color = [1 0 0];
                elseif app.RedSwitch.Value == "Off"
                    writeline(app.tcpClient, "red:0");
                    app.RedLED.Color = [0.3 0 0];
                end
            end
        end

        % Value changed function: GreenSwitch
        function GreenSwitchValueChanged(app, event)
            if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                if app.GreenSwitch.Value == "On"
                    writeline(app.tcpClient, "green:1");
                    app.GreenLED.Color = [0 1 0];
                elseif app.GreenSwitch.Value == "Off"
                    writeline(app.tcpClient, "green:0");
                    app.GreenLED.Color = [0 0.3 0];
                end
            end
        end

        % Value changed function: BlueSwitch
        function BlueSwitchValueChanged(app, event)
            if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                if app.BlueSwitch.Value == "On"
                    writeline(app.tcpClient, "blue:1");
                    app.BlueLED.Color = [0 0 1];
                elseif app.BlueSwitch.Value == "Off"
                    writeline(app.tcpClient, "blue:0");
                    app.BlueLED.Color = [0 0 0.3];
                end
            end
        end

        % Value changed function: TempSwitch
        function TempSwitchValueChanged(app, event)
            if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                if app.TempSwitch.Value == "On"
                    writeline(app.tcpClient, "temp:1");
                elseif app.TempSwitch.Value == "Off"
                    writeline(app.tcpClient, "temp:0");
                    app.Temp.Text = "NULL";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MCXTCPUIFigure = uifigure('Visible', 'off');
            app.MCXTCPUIFigure.Name = 'MCX TCP调试助手';
            app.MCXTCPUIFigure.Icon = fullfile(pathToMLAPP, '网络调试助手图标.png');
            app.IPLabel = uilabel(app.MCXTCPUIFigure);
            app.IPLabel.Text = '服务器IP';
            app.IPEdit = uieditfield(app.MCXTCPUIFigure, 'text');
            app.IPEdit.Value = '192.168.0.102';
            app.Label = uilabel(app.MCXTCPUIFigure);
            app.Label.Text = '网络端口';
            app.PortEdit = uieditfield(app.MCXTCPUIFigure, 'numeric');
            app.PortEdit.Value = 5001;
            app.ConnectButton = uibutton(app.MCXTCPUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = '连接';
            app.ConnectLED = uilamp(app.MCXTCPUIFigure);
            app.LEDLampLabel = uilabel(app.MCXTCPUIFigure);
            app.LEDLampLabel.Text = '红色LED';
            app.RedLED = uilamp(app.MCXTCPUIFigure);
            app.RedSwitch = uiswitch(app.MCXTCPUIFigure, 'slider');
            app.RedSwitch.ValueChangedFcn = createCallbackFcn(app, @RedSwitchValueChanged, true);
            app.RedSwitch.Enable = 'off';
            app.LEDLamp_2Label = uilabel(app.MCXTCPUIFigure);
            app.LEDLamp_2Label.Text = '绿色LED';
            app.GreenLED = uilamp(app.MCXTCPUIFigure);
            app.GreenSwitch = uiswitch(app.MCXTCPUIFigure, 'slider');
            app.GreenSwitch.ValueChangedFcn = createCallbackFcn(app, @GreenSwitchValueChanged, true);
            app.GreenSwitch.Enable = 'off';
            app.LEDLamp_3Label = uilabel(app.MCXTCPUIFigure);
            app.LEDLamp_3Label.Text = '蓝色LED';
            app.BlueLED = uilamp(app.MCXTCPUIFigure);
            app.BlueSwitch = uiswitch(app.MCXTCPUIFigure, 'slider');
            app.BlueSwitch.ValueChangedFcn = createCallbackFcn(app, @BlueSwitchValueChanged, true);
            app.BlueSwitch.Enable = 'off';
            app.TempSwitch = uiswitch(app.MCXTCPUIFigure, 'slider');
            app.TempSwitch.ValueChangedFcn = createCallbackFcn(app, @TempSwitchValueChanged, true);
            app.TempSwitch.Enable = 'off';
            app.Label_2 = uilabel(app.MCXTCPUIFigure);
            app.Label_2.Text = '温度更新开关';
            app.Label_3 = uilabel(app.MCXTCPUIFigure);
            app.Label_3.Text = '温度：';
            app.Temp = uilabel(app.MCXTCPUIFigure);
            app.Temp.Text = 'NULL';
            app.TouchLampLabel = uilabel(app.MCXTCPUIFigure);
            app.TouchLampLabel.Text = 'Touch';
            app.TouchLED = uilamp(app.MCXTCPUIFigure);
            app.Label_4 = uilabel(app.MCXTCPUIFigure);
            app.Label_4.Text = '按键开关';
            app.Switch = uiswitch(app.MCXTCPUIFigure, 'toggle');
            % Show the figure after all components are created
            app.MCXTCPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCXTCPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCXTCPUIFigure)
        end
    end
end